classdef ResectionApps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BMIButtonGroup             matlab.ui.container.ButtonGroup
        BMI_No                     matlab.ui.control.RadioButton
        BMI_Yes                    matlab.ui.control.RadioButton
        PriorTACEButtonGroup       matlab.ui.container.ButtonGroup
        PTACE_No                   matlab.ui.control.RadioButton
        PTACE_Yes                  matlab.ui.control.RadioButton
        AgeButtonGroup             matlab.ui.container.ButtonGroup
        Age_No                     matlab.ui.control.RadioButton
        Age_Yes                    matlab.ui.control.RadioButton
        BilirubinButtonGroup       matlab.ui.container.ButtonGroup
        Bili_No                    matlab.ui.control.RadioButton
        Bili_Yes                   matlab.ui.control.RadioButton
        AlbuminButtonGroup         matlab.ui.container.ButtonGroup
        Alb_No                     matlab.ui.control.RadioButton
        Alb_Yes                    matlab.ui.control.RadioButton
        ExportResulttoxlsxButton   matlab.ui.control.Button
        NonRecurrenceLamp          matlab.ui.control.Lamp
        NonRecurrenceLampLabel     matlab.ui.control.Label
        RecurrenceLamp             matlab.ui.control.Lamp
        RecurrenceLampLabel        matlab.ui.control.Label
        Scrolldowntabletoseesimilarpatientswithsimilarity80Label  matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        OutcomeTextArea            matlab.ui.control.TextArea
        OutcomeTextAreaLabel       matlab.ui.control.Label
        PatientDataLabel           matlab.ui.control.Label
        DMButtonGroup              matlab.ui.container.ButtonGroup
        DM_No                      matlab.ui.control.RadioButton
        DM_Yes                     matlab.ui.control.RadioButton
        AFPButtonGroup             matlab.ui.container.ButtonGroup
        AFP_No                     matlab.ui.control.RadioButton
        AFP_middle                 matlab.ui.control.RadioButton
        AFP_Yes                    matlab.ui.control.RadioButton
        LymphovascularinvasionButtonGroup  matlab.ui.container.ButtonGroup
        LVI_No                     matlab.ui.control.RadioButton
        LVI_Yes                    matlab.ui.control.RadioButton
        SexButtonGroup             matlab.ui.container.ButtonGroup
        FemaleButton               matlab.ui.control.RadioButton
        MaleButton                 matlab.ui.control.RadioButton
        CirrhosisButtonGroup       matlab.ui.container.ButtonGroup
        Cirr_No                    matlab.ui.control.RadioButton
        Cirr_Yes                   matlab.ui.control.RadioButton
        IHDButtonGroup             matlab.ui.container.ButtonGroup
        IHD_No                     matlab.ui.control.RadioButton
        IHD_Yes                    matlab.ui.control.RadioButton
        NoLesionsButtonGroup       matlab.ui.container.ButtonGroup
        NLen_no                    matlab.ui.control.RadioButton
        NLen_yes                   matlab.ui.control.RadioButton
        LiverDiseaseButtonGroup    matlab.ui.container.ButtonGroup
        Others_LD                  matlab.ui.control.RadioButton
        AlcoholButton              matlab.ui.control.RadioButton
        HepCButton                 matlab.ui.control.RadioButton
        HepBButton                 matlab.ui.control.RadioButton
        NAFLDButton                matlab.ui.control.RadioButton
        SatelliteButtonGroup       matlab.ui.container.ButtonGroup
        Sat_No                     matlab.ui.control.RadioButton
        Sat_Yes                    matlab.ui.control.RadioButton
        HPVGButtonGroup            matlab.ui.container.ButtonGroup
        HPVG_No                    matlab.ui.control.RadioButton
        HPVG_Yes                   matlab.ui.control.RadioButton
        eGFRButtonGroup            matlab.ui.container.ButtonGroup
        eGFR_No                    matlab.ui.control.RadioButton
        eGFR_Yes                   matlab.ui.control.RadioButton
        SizeofLesioncmButtonGroup  matlab.ui.container.ButtonGroup
        Size_No                    matlab.ui.control.RadioButton
        Size_Yes                   matlab.ui.control.RadioButton
        EthnicityButtonGroup       matlab.ui.container.ButtonGroup
        Others_Ethn                matlab.ui.control.RadioButton
        AsianButton                matlab.ui.control.RadioButton
        CaucasianButton            matlab.ui.control.RadioButton
        INRButtonGroup             matlab.ui.container.ButtonGroup
        INR_No                     matlab.ui.control.RadioButton
        INR_Yes                    matlab.ui.control.RadioButton
        ALTButtonGroup             matlab.ui.container.ButtonGroup
        ALT_No                     matlab.ui.control.RadioButton
        ALT_Yes                    matlab.ui.control.RadioButton
        PredictButton              matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       x_test % data for prediction
       net    % multi layer perceptron model 
       INR
       LiverDisease
       No_Lesions
       Ethnicity
       Cirrhosis
       DM
       HPVG
       ALT
       eGFR
       Albumin
       AFP
       BMI
       Satellite
       LVI
       Sex
       Bilirubin
       Age
       IHD
       Size
       PriorTACE
       xtrain
       ytrain
       prediction
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %#function SeriesNetwork
            app.net = load("BestTrainedModel.mat");
            app.net = app.net.net;
            
            app.INR_No.Value = 1;
            app.Others_LD.Value = 1;
            app.NLen_yes.Value = 1;
            app.AsianButton.Value = 1;
            app.Cirr_No.Value = 1;
            app.DM_Yes.Value = 1;
            app.HPVG_Yes.Value = 1;
            app.ALT_No.Value = 1;
            app.eGFR_No.Value = 1;
            app.Alb_Yes.Value = 1;
            app.AFP_Yes.Value = 1;
            app.BMI_Yes.Value = 1;
            app.Sat_No.Value = 1;
            app.LVI_No.Value = 1;
            app.FemaleButton.Value = 1;
            app.Bili_No.Value = 1;
            app.Age_Yes.Value = 1;
            app.IHD_Yes.Value = 1;
            app.Size_Yes.Value = 1;
            app.PTACE_Yes.Value = 1;

            app.INR = 0;
            app.LiverDisease = 4;
            app.No_Lesions = 1;
            app.Ethnicity = 1;
            app.Cirrhosis = 1;
            app.DM = 1; 
            app.HPVG = 1;
            app.ALT = 0; 
            app.eGFR = 0;
            app.Albumin = 1;
            app.AFP = 1; 
            app.BMI = 1;
            app.Satellite = 0; 
            app.LVI =0; 
            app.Sex = 0; 
            app.Bilirubin = 0;
            app.Age = 1;
            app.IHD = 1;
            app.Size = 1;
            app.PriorTACE = 1;
%             app.UIAxes.XTick = [0,1];
            app.UIAxes.YTick = [];

            app.UITable.ColumnName = {'Recurrence'
               'INR'
               'LiverDisease'
               'No_Lesions'
               'Ethnicity'
               'Cirrhosis'
               'DM'
               'HPVG'
               'ALT'
               'eGFR'
               'Albumin'
               'AFP'
               'BMI'
               'Satellite'
               'LVI'
               'Sex'
               'Bilirubin'
               'Age'
               'IHD'
               'Size'
               'PriorTACE'
                };
            x = load('XTrainModel.mat');
            y = load('YTrainModel.mat');

            app.xtrain = x.XTrain;
            app.ytrain = y.YTrain;

            app.UITable.Data = [app.ytrain app.xtrain];

        end

        % Callback function
        function ViralButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViralButtonGroup.SelectedObject;
            if app.YesButton.Value==1
                app.Viral = 1;
            else
                app.Viral = 0;
            end
        end

        % Callback function
        function PatientDataPanelSizeChanged(app, event)
%             position = app.PatientDataPanel.Position;
%             if app.YesButton.Value==1
%                 Viral = 1;
%             elseif app.NoButton.Value==1
%                 Viral = 0;
%             elseif app.ALT_Yes.Value ==1
%                 ALT = 1;
%             elseif app.ALT_No.Value == 1
%                 ALT = 0;
%             elseif app.CaucasianButton.Value == 1
%                 Ethnicity = 0;
%             elseif app.AsianButton.Value == 1
%                 Ethnicity = 1;
%             elseif app.AfricanButton.Value == 1
%                 Ethnicity = 2;
%             elseif app.EuropeanButton.Value == 1
%                 Ethnicity = 3;
%             elseif app.Others_Ethn == 1
%                 Ethnicity = 4;
%             elseif app.INR_Yes.Value == 1
%                 INR = 1;
%             elseif app.INR_No.Value == 1
%                 INR = 0;
%             elseif app.Size_Yes.Value == 1
%                 Size = 1;
%             elseif app.Size_No.Value == 1
%                 Size = 0;
%             elseif app.Button_7.Value == 1
%                 PlateletCount = 1;
%             elseif app.Button_8.Value == 1
%                 PlateletCount = 0;
%             elseif app.eGFR_Yes.Value == 1
%                 eGFR = 1;
%             elseif app.eGFR_No.Value == 1
%                 eGFR = 0;
%             elseif app.HPVG_Yes.Value == 1
%                 Albumin = 1;
%             elseif app.HPVG_No.Value == 1
%                 Albumin = 0;
%             elseif app.Sat_Yes.Value == 1
%                 Satellite = 1;
%             elseif app.Sat_No.Value == 1
%                 Satellite = 0;
%             elseif app.Button_20.Value == 1
%                 ChronicHepatitisC = 1;
%             elseif app.Button_21.Value == 1
%                 ChronicHepatitisC = 0;
%             elseif app.NAFLDButton.Value == 1
%                 LiverDisease = 0;
%             elseif app.HepBButton.Value == 1
%                 LiverDisease = 1;
%             elseif app.HepCButton.Value == 1
%                 LiverDisease = 2;
%             elseif app.AlcoholButton.Value == 1
%                 LiverDisease = 3;
%             elseif app.Others_LD.Value == 1
%                 LiverDisease = 4;
%             elseif app.NLen_yes.Value ==1
%                 Lesion = 1;
%             elseif app.NLen_no.Value == 1
%                 Lesion = 0; 
%             elseif app.Cirr_Yes.Value == 1
%                 CVS = 1;
%             elseif app.Cirr_No.Value == 1
%                 CVS = 0; 
%             elseif app.AFP_Yes.Value == 1
%                 AFP = 2;
%             elseif app.AFP_middle.Value == 1
%                 AFP = 1; 
%             elseif app.AFP_No.Value == 1
%                 AFP = 0;
%             elseif app.LVI_Yes.Value == 1
%                 DM = 1;
%             elseif app.LVI_No.Value == 1
%                 DM = 0;
%             elseif app.YesButton_10.Value ==1
%                 HB = 1;
%             elseif app.NoButton_10.Value == 1
%                 HB = 0;
%             elseif app.Button_15.Value == 1
%                 MF = 1;
%             elseif app.Button_16.Value ==1
%                 MF = 0; 
%             elseif app.DM_Yes.Value == 1
%                 NF = 1;
%             elseif app.DM_No.Value == 1
%                 NF = 0;
%             elseif app.PTACE_Yes.Value == 1
%                 Cirrhosis = 1;
%             elseif app.PTACE_No.Value == 1
%                 Cirrhosis = 0;
%             elseif app.MaleButton.Value == 1
%                 Sex = 0;
%             elseif app.FemaleButton.Value == 1
%                 Sex = 1;
%             end
            
%             app.x_test = [Viral, ALT, Ethnicity, INR, Size, PlateletCount, eGFR, ...
%                         Albumin, Satellite, ChronicHepatitisC, LiverDisease, ...
%                         Lesion, CVS, AFP, DM, HB, MF, NF, Cirrhosis, Sex];
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.x_test = [app.Satellite,app.eGFR,...
    app.PriorTACE,app.AFP,app.LVI,app.Ethnicity,app.Sex,...
    app.Albumin,app.No_Lesions,app.Size,app.Cirrhosis,app.BMI,...
    app.Age,app.ALT,app.INR,app.IHD,app.DM,app.LiverDisease,...
    app.HPVG,app.Bilirubin];
%             app.OutputEditField.Value = double(app.x_test);
            app.prediction = app.net.test(app.x_test);
            app.prediction(2) = 1-app.prediction(1);
%             x = categorical({'No','Yes'});
            barh(app.UIAxes,[1;nan], [app.prediction(end:-1:1); nan(1,2)], 'stacked')
            legend(app.UIAxes,{'Yes','No'},'Location','northwest')
%             app.UIAxes.XTick = [0,1];
            xlim(app.UIAxes,[0, 1])
            app.UIAxes.YTick = [];

            app.OutcomeTextArea.Value = ['This patient has ' num2str(100*app.prediction(2),'%3.2f') ...
                                        '% chance to experience tumour recurrence and ' ...
                                        num2str(100-100*app.prediction(2),'%3.2f') '% chance for non-recurrence.'];

            for i=1:size(app.xtrain,1)
                a = app.x_test;
                b = app.xtrain(i,:);
                cosSim(i) = dot(a,b)/(norm(a)*norm(b)); 
            end
            id_sim = find(cosSim>0.8);
  
            for i = id_sim
                if app.ytrain(i)>0
                    s = uistyle('BackgroundColor',[0 0.4470 0.7410]);
                    ii = i*ones(21,1);
                    jj = 1:21;
                    addStyle(app.UITable,s,'cell',[ii,jj']);
                else
                    s = uistyle('BackgroundColor',[0.8500 0.3250 0.0980]);
                    ii = i*ones(21,1);
                    jj = 1:21;
                    addStyle(app.UITable,s,'cell',[ii,jj']);
                end
            end


%             b = bar(app.UIAxes,x,prediction);
        end

        % Callback function
        function UITableSelectionChanged(app, event)
%             selection = app.UITable.Selection;
%             app.UITable.Data = app.x_test
        end

        % Selection changed function: EthnicityButtonGroup
        function EthnicityButtonGroupSelectionChanged(app, event)
            selectedButton = app.EthnicityButtonGroup.SelectedObject;
            if app.CaucasianButton.Value == 1
                app.Ethnicity = 0;
            elseif app.AsianButton.Value == 1
                app.Ethnicity = 1;
            else
                app.Ethnicity = 2;
            end
        end

        % Selection changed function: ALTButtonGroup
        function ALTButtonGroupSelectionChanged(app, event)
            selectedButton = app.ALTButtonGroup.SelectedObject;
            if app.ALT_Yes.Value ==1
                app.ALT = 1;
            else
                app.ALT = 0;
            end
        end

        % Selection changed function: INRButtonGroup
        function INRButtonGroupSelectionChanged(app, event)
            selectedButton = app.INRButtonGroup.SelectedObject;
            if app.INR_Yes.Value == 1
                app.INR = 1;
            else 
                app.INR = 0;
            end
        end

        % Selection changed function: SizeofLesioncmButtonGroup
        function SizeofLesioncmButtonGroupSelectionChanged(app, event)
            selectedButton = app.SizeofLesioncmButtonGroup.SelectedObject;
            if app.Size_Yes.Value == 1
                app.Size = 1;
            else 
                app.Size = 0;
            end
        end

        % Callback function
        function PlateletCountButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlateletCountButtonGroup.SelectedObject;
            if app.Button_7.Value == 1
                app.PlateletCount = 1;
            else 
                app.PlateletCount = 0;
            end
        end

        % Selection changed function: eGFRButtonGroup
        function eGFRButtonGroupSelectionChanged(app, event)
            selectedButton = app.eGFRButtonGroup.SelectedObject;
            if app.eGFR_Yes.Value == 1
                app.eGFR = 1;
            else
                app.eGFR = 0;
            end
        end

        % Selection changed function: HPVGButtonGroup
        function HPVGButtonGroupSelectionChanged(app, event)
            selectedButton = app.HPVGButtonGroup.SelectedObject;
            if app.HPVG_Yes.Value == 1
                app.HPVG = 1;
            else
                app.HPVG = 0;
            end
        end

        % Selection changed function: SatelliteButtonGroup
        function SatelliteButtonGroupSelectionChanged(app, event)
            selectedButton = app.SatelliteButtonGroup.SelectedObject;
            if app.Sat_Yes.Value == 1
                app.Satellite = 1;
            else 
                app.Satellite = 0;
            end
        end

        % Selection changed function: BMIButtonGroup
        function BMIButtonGroupSelectionChanged(app, event)
            selectedButton = app.BMIButtonGroup.SelectedObject;
            if app.BMI_Yes.Value == 1
                app.BMI = 1;
            else
                app.BMI = 0;
            end
        end

        % Selection changed function: LiverDiseaseButtonGroup
        function LiverDiseaseButtonGroupSelectionChanged(app, event)
            selectedButton = app.LiverDiseaseButtonGroup.SelectedObject;
            if app.NAFLDButton.Value == 1
                app.LiverDisease = 0;
            elseif app.HepBButton.Value == 1
                app.LiverDisease = 1;
            elseif app.HepCButton.Value == 1
                app.LiverDisease = 2;
            elseif app.AlcoholButton.Value == 1
                app.LiverDisease = 3;
            else
                app.LiverDisease = 4;
            end
        end

        % Selection changed function: NoLesionsButtonGroup
        function NoLesionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.NoLesionsButtonGroup.SelectedObject;
            if app.NLen_yes.Value ==1
                app.No_Lesions = 1;
            else
                app.No_Lesions = 0; 
            end
        end

        % Selection changed function: IHDButtonGroup
        function IHDButtonGroupSelectionChanged(app, event)
            selectedButton = app.IHDButtonGroup.SelectedObject;
            if app.IHD_Yes.Value == 1
                app.IHD = 1;
            else 
                app.IHD = 0; 
            end
        end

        % Selection changed function: AFPButtonGroup
        function AFPButtonGroupSelectionChanged(app, event)
            selectedButton = app.AFPButtonGroup.SelectedObject;
            if app.AFP_Yes.Value == 1
                app.AFP = 2;
            elseif app.AFP_middle.Value == 1
                app.AFP = 1; 
            else
                app.AFP = 0;
            end
        end

        % Selection changed function: DMButtonGroup
        function DMButtonGroupSelectionChanged(app, event)
            selectedButton = app.DMButtonGroup.SelectedObject;
            if app.DM_Yes.Value == 1
                app.DM = 1;
            else
                app.DM = 0;
            end
        end

        % Selection changed function: LymphovascularinvasionButtonGroup
        function LymphovascularinvasionButtonGroupSelectionChanged(app, event)
            selectedButton = app.LymphovascularinvasionButtonGroup.SelectedObject;
            if app.LVI_Yes.Value ==1
                app.LVI = 1;
            else
                app.LVI = 0;
            end
        end

        % Callback function
        function MetabolicRiskFactorsButtonGroupSelectionChanged(app, event)
            selectedButton = app.MetabolicRiskFactorsButtonGroup.SelectedObject;
            if app.Button_15.Value == 1
                app.MF = 1;
            else
                app.MF = 0; 
            end
        end

        % Callback function
        function NAFLDButtonGroupSelectionChanged(app, event)
            selectedButton = app.NAFLDButtonGroup.SelectedObject;
            if app.YesButton_5.Value == 1
                app.NF = 1;
            else
                app.NF = 0;
            end
        end

        % Selection changed function: CirrhosisButtonGroup
        function CirrhosisButtonGroupSelectionChanged(app, event)
            selectedButton = app.CirrhosisButtonGroup.SelectedObject;
            if app.Cirr_Yes.Value == 1
                app.Cirrhosis = 1;
            else
                app.Cirrhosis = 0;
            end
        end

        % Selection changed function: SexButtonGroup
        function SexButtonGroupSelectionChanged(app, event)
            selectedButton = app.SexButtonGroup.SelectedObject;
            if app.MaleButton.Value == 1
                app.Sex = 1;
            else
                app.Sex = 0;
            end
        end

        % Callback function
        function VectorEditFieldValueChanged(app, event)
            app.VectorEditField.Value = num2str(app.x_test);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.UITable.ColumnName = [
                'INR'
               'LiverDisease'
               'No_Lesions'
               'Ethnicity'
               'Cirrhosis'
               'DM'
               'HPVG'
               'ALT'
               'eGFR'
               'Albumin'
               'AFP'
               'BMI'
               'Satellite'
               'LVI'
               'Sex'
               'Bilirubin'
               'Age'
               'IHD'
               'Size'
               'PriorTACE'];
        end

        % Button pushed function: ExportResulttoxlsxButton
        function ExportResulttoxlsxButtonPushed(app, event)
            for i=1:size(app.xtrain,1)
                a = app.x_test;
                b = app.xtrain(i,:);
                cosSim(i) = dot(a,b)/(norm(a)*norm(b)); 
            end
            id_sim = find(cosSim>0.8);

            T = array2table([app.ytrain(id_sim) app.xtrain(id_sim,:)]);
            T.Properties.VariableNames = {'Recurrence'
                'INR'
               'LiverDisease'
               'No_Lesions'
               'Ethnicity'
               'Cirrhosis'
               'DM'
               'HPVG'
               'ALT'
               'eGFR'
               'Albumin'
               'AFP'
               'BMI'
               'Satellite'
               'LVI'
               'Sex'
               'Bilirubin'
               'Age'
               'IHD'
               'Size'
               'PriorTACE'};
            filename = 'Recurrence_Result.xlsx';
            writetable(T,filename,'Sheet','Similar Patients','WriteMode','overwritesheet')
            A = {'Chance of Recurrence','Chance of Non-Recurrence'; 100*app.prediction(2),100*(1-app.prediction(2))};
            
            writecell(A,filename,'Sheet','Probability_Result');
            
%             e = actxserver('Excel.Application');
%             ewb = e.Workbooks.Open('Recurrence_Result.xls'); 
%             ewb.Worksheets.Item(1).Name = 'Similar Patients'; 
%             ewb.Worksheets.Item(2).Name = 'Probability_Result';
%             ewb.Save 
%             ewb.Close(false)
%             e.Quit
        end

        % Selection changed function: AlbuminButtonGroup
        function AlbuminButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlbuminButtonGroup.SelectedObject;
            if app.Alb_Yes.Value == 1
                app.Albumin = 0;
            else
                app.Albumin = 1;
            end
        end

        % Selection changed function: BilirubinButtonGroup
        function BilirubinButtonGroupSelectionChanged(app, event)
            selectedButton = app.BilirubinButtonGroup.SelectedObject;
            if app.Bili_Yes.Value == 1
                app.Bilirubin = 1;
            else
                app.Bilirubin = 0;
            end
        end

        % Selection changed function: AgeButtonGroup
        function AgeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AgeButtonGroup.SelectedObject;
            if app.Age_Yes.Value == 1
                app.Age = 1;
            else
                app.Age = 0;
            end
        end

        % Selection changed function: PriorTACEButtonGroup
        function PriorTACEButtonGroupSelectionChanged(app, event)
            selectedButton = app.PriorTACEButtonGroup.SelectedObject;
            if app.PTACE_Yes.Value == 1
                app.PriorTACE = 1;
            else
                app.PriorTACE = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Prediction Probability of Recurrence after 1 year')
            xlabel(app.UIAxes, 'Probability')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ALTButtonGroup = uibuttongroup(app.UIFigure);
            app.ALTButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ALTButtonGroupSelectionChanged, true);
            app.ALTButtonGroup.Title = 'ALT';
            app.ALT_Yes = uiradiobutton(app.ALTButtonGroup);
            app.ALT_Yes.Text = '>50';
            app.ALT_Yes.Value = true;
            app.ALT_No = uiradiobutton(app.ALTButtonGroup);
            app.ALT_No.Text = '<=50';
            app.INRButtonGroup = uibuttongroup(app.UIFigure);
            app.INRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @INRButtonGroupSelectionChanged, true);
            app.INRButtonGroup.Title = 'INR';
            app.INR_Yes = uiradiobutton(app.INRButtonGroup);
            app.INR_Yes.Text = '>1.1';
            app.INR_Yes.Value = true;
            app.INR_No = uiradiobutton(app.INRButtonGroup);
            app.INR_No.Text = '<=1.1';
            app.EthnicityButtonGroup = uibuttongroup(app.UIFigure);
            app.EthnicityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EthnicityButtonGroupSelectionChanged, true);
            app.EthnicityButtonGroup.Title = 'Ethnicity';
            app.CaucasianButton = uiradiobutton(app.EthnicityButtonGroup);
            app.CaucasianButton.Text = 'Caucasian';
            app.CaucasianButton.Value = true;
            app.AsianButton = uiradiobutton(app.EthnicityButtonGroup);
            app.AsianButton.Text = 'Asian';
            app.Others_Ethn = uiradiobutton(app.EthnicityButtonGroup);
            app.Others_Ethn.Text = 'Others';
            app.SizeofLesioncmButtonGroup = uibuttongroup(app.UIFigure);
            app.SizeofLesioncmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SizeofLesioncmButtonGroupSelectionChanged, true);
            app.SizeofLesioncmButtonGroup.Title = 'Size of Lesion (cm)';
            app.Size_Yes = uiradiobutton(app.SizeofLesioncmButtonGroup);
            app.Size_Yes.Text = '>=5';
            app.Size_Yes.Value = true;
            app.Size_No = uiradiobutton(app.SizeofLesioncmButtonGroup);
            app.Size_No.Text = '<5';
            app.eGFRButtonGroup = uibuttongroup(app.UIFigure);
            app.eGFRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @eGFRButtonGroupSelectionChanged, true);
            app.eGFRButtonGroup.Title = 'eGFR';
            app.eGFR_Yes = uiradiobutton(app.eGFRButtonGroup);
            app.eGFR_Yes.Text = '>=90';
            app.eGFR_Yes.Value = true;
            app.eGFR_No = uiradiobutton(app.eGFRButtonGroup);
            app.eGFR_No.Text = '<90';
            app.HPVGButtonGroup = uibuttongroup(app.UIFigure);
            app.HPVGButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HPVGButtonGroupSelectionChanged, true);
            app.HPVGButtonGroup.Title = 'HPVG';
            app.HPVG_Yes = uiradiobutton(app.HPVGButtonGroup);
            app.HPVG_Yes.Text = '>5';
            app.HPVG_Yes.Value = true;
            app.HPVG_No = uiradiobutton(app.HPVGButtonGroup);
            app.HPVG_No.Text = '<=5';
            app.SatelliteButtonGroup = uibuttongroup(app.UIFigure);
            app.SatelliteButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SatelliteButtonGroupSelectionChanged, true);
            app.SatelliteButtonGroup.Title = 'Satellite';
            app.Sat_Yes = uiradiobutton(app.SatelliteButtonGroup);
            app.Sat_Yes.Text = 'Yes';
            app.Sat_Yes.Value = true;
            app.Sat_No = uiradiobutton(app.SatelliteButtonGroup);
            app.Sat_No.Text = 'No';
            app.LiverDiseaseButtonGroup = uibuttongroup(app.UIFigure);
            app.LiverDiseaseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LiverDiseaseButtonGroupSelectionChanged, true);
            app.LiverDiseaseButtonGroup.Title = 'Liver Disease';
            app.NAFLDButton = uiradiobutton(app.LiverDiseaseButtonGroup);
            app.NAFLDButton.Text = 'NAFLD';
            app.NAFLDButton.Value = true;
            app.HepBButton = uiradiobutton(app.LiverDiseaseButtonGroup);
            app.HepBButton.Text = 'Hep B';
            app.HepCButton = uiradiobutton(app.LiverDiseaseButtonGroup);
            app.HepCButton.Text = 'Hep C';
            app.AlcoholButton = uiradiobutton(app.LiverDiseaseButtonGroup);
            app.AlcoholButton.Text = 'Alcohol';
            app.Others_LD = uiradiobutton(app.LiverDiseaseButtonGroup);
            app.Others_LD.Text = 'Others';
            app.NoLesionsButtonGroup = uibuttongroup(app.UIFigure);
            app.NoLesionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoLesionsButtonGroupSelectionChanged, true);
            app.NoLesionsButtonGroup.Title = 'No Lesions';
            app.NLen_yes = uiradiobutton(app.NoLesionsButtonGroup);
            app.NLen_yes.Text = '>1';
            app.NLen_yes.Value = true;
            app.NLen_no = uiradiobutton(app.NoLesionsButtonGroup);
            app.NLen_no.Text = '<=1';
            app.IHDButtonGroup = uibuttongroup(app.UIFigure);
            app.IHDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IHDButtonGroupSelectionChanged, true);
            app.IHDButtonGroup.Title = 'IHD';
            app.IHD_Yes = uiradiobutton(app.IHDButtonGroup);
            app.IHD_Yes.Text = 'Yes';
            app.IHD_Yes.Value = true;
            app.IHD_No = uiradiobutton(app.IHDButtonGroup);
            app.IHD_No.Text = 'No';
            app.CirrhosisButtonGroup = uibuttongroup(app.UIFigure);
            app.CirrhosisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CirrhosisButtonGroupSelectionChanged, true);
            app.CirrhosisButtonGroup.Title = 'Cirrhosis';
            app.Cirr_Yes = uiradiobutton(app.CirrhosisButtonGroup);
            app.Cirr_Yes.Text = 'Yes';
            app.Cirr_Yes.Value = true;
            app.Cirr_No = uiradiobutton(app.CirrhosisButtonGroup);
            app.Cirr_No.Text = 'No';
            app.SexButtonGroup = uibuttongroup(app.UIFigure);
            app.SexButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SexButtonGroupSelectionChanged, true);
            app.SexButtonGroup.Title = 'Sex';
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.LymphovascularinvasionButtonGroup = uibuttongroup(app.UIFigure);
            app.LymphovascularinvasionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LymphovascularinvasionButtonGroupSelectionChanged, true);
            app.LymphovascularinvasionButtonGroup.Title = 'Lymphovascular invasion';
            app.LVI_Yes = uiradiobutton(app.LymphovascularinvasionButtonGroup);
            app.LVI_Yes.Text = 'Yes';
            app.LVI_Yes.Value = true;
            app.LVI_No = uiradiobutton(app.LymphovascularinvasionButtonGroup);
            app.LVI_No.Text = 'No';
            app.AFPButtonGroup = uibuttongroup(app.UIFigure);
            app.AFPButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AFPButtonGroupSelectionChanged, true);
            app.AFPButtonGroup.Title = 'AFP';
            app.AFP_Yes = uiradiobutton(app.AFPButtonGroup);
            app.AFP_Yes.Text = '>100';
            app.AFP_Yes.Value = true;
            app.AFP_middle = uiradiobutton(app.AFPButtonGroup);
            app.AFP_middle.Text = '> 8 & <=100';
            app.AFP_No = uiradiobutton(app.AFPButtonGroup);
            app.AFP_No.Text = '<=8';
            app.DMButtonGroup = uibuttongroup(app.UIFigure);
            app.DMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DMButtonGroupSelectionChanged, true);
            app.DMButtonGroup.Title = 'DM';
            app.DM_Yes = uiradiobutton(app.DMButtonGroup);
            app.DM_Yes.Text = 'Yes';
            app.DM_Yes.Value = true;
            app.DM_No = uiradiobutton(app.DMButtonGroup);
            app.DM_No.Text = 'No';
            app.PatientDataLabel = uilabel(app.UIFigure);
            app.PatientDataLabel.Text = 'Patient Data';
            app.OutcomeTextAreaLabel = uilabel(app.UIFigure);
            app.OutcomeTextAreaLabel.Text = 'Outcome';
            app.OutcomeTextArea = uitextarea(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Scrolldowntabletoseesimilarpatientswithsimilarity80Label = uilabel(app.UIFigure);
            app.Scrolldowntabletoseesimilarpatientswithsimilarity80Label.Text = 'Scroll down table to see similar patients with similarity > 80%';
            app.RecurrenceLampLabel = uilabel(app.UIFigure);
            app.RecurrenceLampLabel.Text = 'Recurrence';
            app.RecurrenceLamp = uilamp(app.UIFigure);
            app.NonRecurrenceLampLabel = uilabel(app.UIFigure);
            app.NonRecurrenceLampLabel.Text = 'Non-Recurrence';
            app.NonRecurrenceLamp = uilamp(app.UIFigure);
            app.ExportResulttoxlsxButton = uibutton(app.UIFigure, 'push');
            app.ExportResulttoxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResulttoxlsxButtonPushed, true);
            app.ExportResulttoxlsxButton.Text = 'Export Result to xlsx';
            app.AlbuminButtonGroup = uibuttongroup(app.UIFigure);
            app.AlbuminButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlbuminButtonGroupSelectionChanged, true);
            app.AlbuminButtonGroup.Title = 'Albumin';
            app.Alb_Yes = uiradiobutton(app.AlbuminButtonGroup);
            app.Alb_Yes.Text = '>=35';
            app.Alb_Yes.Value = true;
            app.Alb_No = uiradiobutton(app.AlbuminButtonGroup);
            app.Alb_No.Text = '<35';
            app.BilirubinButtonGroup = uibuttongroup(app.UIFigure);
            app.BilirubinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BilirubinButtonGroupSelectionChanged, true);
            app.BilirubinButtonGroup.Title = 'Bilirubin';
            app.Bili_Yes = uiradiobutton(app.BilirubinButtonGroup);
            app.Bili_Yes.Text = '>20';
            app.Bili_Yes.Value = true;
            app.Bili_No = uiradiobutton(app.BilirubinButtonGroup);
            app.Bili_No.Text = '<=20';
            app.AgeButtonGroup = uibuttongroup(app.UIFigure);
            app.AgeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AgeButtonGroupSelectionChanged, true);
            app.AgeButtonGroup.Title = 'Age';
            app.Age_Yes = uiradiobutton(app.AgeButtonGroup);
            app.Age_Yes.Text = '>=65';
            app.Age_Yes.Value = true;
            app.Age_No = uiradiobutton(app.AgeButtonGroup);
            app.Age_No.Text = '<65';
            app.PriorTACEButtonGroup = uibuttongroup(app.UIFigure);
            app.PriorTACEButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PriorTACEButtonGroupSelectionChanged, true);
            app.PriorTACEButtonGroup.Title = 'Prior TACE';
            app.PTACE_Yes = uiradiobutton(app.PriorTACEButtonGroup);
            app.PTACE_Yes.Text = 'Yes';
            app.PTACE_Yes.Value = true;
            app.PTACE_No = uiradiobutton(app.PriorTACEButtonGroup);
            app.PTACE_No.Text = 'No';
            app.BMIButtonGroup = uibuttongroup(app.UIFigure);
            app.BMIButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BMIButtonGroupSelectionChanged, true);
            app.BMIButtonGroup.Title = 'BMI';
            app.BMI_Yes = uiradiobutton(app.BMIButtonGroup);
            app.BMI_Yes.Text = '>=25';
            app.BMI_Yes.Value = true;
            app.BMI_No = uiradiobutton(app.BMIButtonGroup);
            app.BMI_No.Text = '<25';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResectionApps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end